classdef EditStrategyAnalysisTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        WordSuggestedProbLabel         matlab.ui.control.Label
        WordSuggestedProb              matlab.ui.control.Slider
        WordAutocorrectedProbLabel     matlab.ui.control.Label
        WordFixProb                    matlab.ui.control.Slider
        InputCorrectionStrategyButtonGroup  matlab.ui.container.ButtonGroup
        WordLevelFixesButton           matlab.ui.control.ToggleButton
        CharacterLevelFixesButton      matlab.ui.control.ToggleButton
        WordSubstituionMeanTimesLabel  matlab.ui.control.Label
        WordSubTimeMean                matlab.ui.control.Slider
        ErrorNoticedProbLabel          matlab.ui.control.Label
        CharacterInsertionErrorProbLabel  matlab.ui.control.Label
        CharacterDeletionMeanTimesLabel  matlab.ui.control.Label
        ErrorNoticeProb                matlab.ui.control.Slider
        CharacterInsertionMeanTimesLabel  matlab.ui.control.Label
        CharDelTimeMean                matlab.ui.control.Slider
        CharErroProb                   matlab.ui.control.Slider
        CharInsTimeMean                matlab.ui.control.Slider
        RightPanel                     matlab.ui.container.Panel
        UIAxesKSPC                     matlab.ui.control.UIAxes
        UIAxesCER                      matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = private)
        
        function results = updatePdfPlot(app)
            charInsTimeMean = app.CharInsTimeMean.Value;
            charDelTimeMean = app.CharDelTimeMean.Value;
            pError = app.CharErroProb.Value;
            pNotice = app.ErrorNoticeProb.Value;
            pWordSuggest = app.WordSuggestedProb.Value;
            wordSubTimeMean = app.WordSubTimeMean.Value;
            pWordFix = app.WordFixProb.Value;
            delStrategy = app.CharacterLevelFixesButton.Value;
            reps = 100;
            [T, CER, KSPC] = editStrategyModel(charInsTimeMean,charDelTimeMean,wordSubTimeMean,pError,pNotice,pWordFix,pWordSuggest,delStrategy,reps);
            histogram(app.UIAxes,T,[0:5:120],'Normalization','probability')
            app.UIAxes.XLim = [0 120];
            app.UIAxes.XTick = [0:5:120];
            app.UIAxes.YLim = [0 0.5];
            hold(app.UIAxes,'on');
            tMedian = median(T);
            plot(app.UIAxes,[tMedian tMedian],[0 1],'r:');
            hold(app.UIAxes,'off');            
            
            histogram(app.UIAxesCER,CER,[0:2.5:100],'Normalization','probability')
            app.UIAxesCER.XLim = [0 100];
            app.UIAxesCER.YLim = [0 1.0];
            
            histogram(app.UIAxesKSPC,KSPC,[1:0.2:3],'Normalization','probability')
            app.UIAxesKSPC.XLim = [1 3];
            app.UIAxesKSPC.YLim = [0 1.0];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            updatePdfPlot(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {640, 640};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {268, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Callback function
        function CharInsTimeMeanValueChanging(app, event)
            
        end

        % Value changed function: CharInsTimeMean
        function CharInsTimeMeanValueChanged(app, event)
            updatePdfPlot(app);
        end

        % Value changed function: CharErroProb
        function CharErroProbValueChanged(app, event)
            updatePdfPlot(app);            
        end

        % Value changed function: CharDelTimeMean
        function CharDelTimeMeanValueChanged(app, event)
            updatePdfPlot(app);            
        end

        % Value changed function: ErrorNoticeProb
        function ErrorNoticeProbValueChanged(app, event)
            updatePdfPlot(app);
        end

        % Selection changed function: InputCorrectionStrategyButtonGroup
        function InputCorrectionStrategyButtonGroupSelectionChanged(app, event)
            updatePdfPlot(app);
        end

        % Value changed function: WordSubTimeMean
        function WordSubTimeMeanValueChanged(app, event)
            updatePdfPlot(app);
        end

        % Value changed function: WordFixProb
        function WordFixProbValueChanged(app, event)
            updatePdfPlot(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.CharInsTimeMean = uislider(app.LeftPanel);
            app.CharInsTimeMean.Limits = [0 2];
            app.CharInsTimeMean.ValueChangedFcn = createCallbackFcn(app, @CharInsTimeMeanValueChanged, true);
            app.CharInsTimeMean.Value = 0.5;
            app.CharErroProb = uislider(app.LeftPanel);
            app.CharErroProb.Limits = [0 1];
            app.CharErroProb.ValueChangedFcn = createCallbackFcn(app, @CharErroProbValueChanged, true);
            app.CharErroProb.Value = 0.1;
            app.CharDelTimeMean = uislider(app.LeftPanel);
            app.CharDelTimeMean.Limits = [0 5];
            app.CharDelTimeMean.ValueChangedFcn = createCallbackFcn(app, @CharDelTimeMeanValueChanged, true);
            app.CharDelTimeMean.Value = 1.5;
            app.CharacterInsertionMeanTimesLabel = uilabel(app.LeftPanel);
            app.CharacterInsertionMeanTimesLabel.Text = 'Character Insertion Mean Time (s)';
            app.ErrorNoticeProb = uislider(app.LeftPanel);
            app.ErrorNoticeProb.Limits = [0 1];
            app.ErrorNoticeProb.ValueChangedFcn = createCallbackFcn(app, @ErrorNoticeProbValueChanged, true);
            app.ErrorNoticeProb.Value = 0.8;
            app.CharacterDeletionMeanTimesLabel = uilabel(app.LeftPanel);
            app.CharacterDeletionMeanTimesLabel.Text = 'Character Deletion Mean Time (s)';
            app.CharacterInsertionErrorProbLabel = uilabel(app.LeftPanel);
            app.CharacterInsertionErrorProbLabel.Text = 'Character Insertion Error Prob. (p)';
            app.ErrorNoticedProbLabel = uilabel(app.LeftPanel);
            app.ErrorNoticedProbLabel.Text = 'Error Noticed Prob. (p)';
            app.WordSubTimeMean = uislider(app.LeftPanel);
            app.WordSubTimeMean.Limits = [0 10];
            app.WordSubTimeMean.ValueChangedFcn = createCallbackFcn(app, @WordSubTimeMeanValueChanged, true);
            app.WordSubTimeMean.Value = 10;
            app.WordSubstituionMeanTimesLabel = uilabel(app.LeftPanel);
            app.WordSubstituionMeanTimesLabel.Text = 'Word Substituion Mean Time (s)';
            app.InputCorrectionStrategyButtonGroup = uibuttongroup(app.LeftPanel);
            app.InputCorrectionStrategyButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @InputCorrectionStrategyButtonGroupSelectionChanged, true);
            app.InputCorrectionStrategyButtonGroup.Title = 'Input Correction Strategy';
            app.CharacterLevelFixesButton = uitogglebutton(app.InputCorrectionStrategyButtonGroup);
            app.CharacterLevelFixesButton.Text = 'Character-Level Fixes';
            app.CharacterLevelFixesButton.Value = true;
            app.WordLevelFixesButton = uitogglebutton(app.InputCorrectionStrategyButtonGroup);
            app.WordLevelFixesButton.Text = 'Word-Level Fixes';
            app.WordFixProb = uislider(app.LeftPanel);
            app.WordFixProb.Limits = [0 1];
            app.WordFixProb.ValueChangedFcn = createCallbackFcn(app, @WordFixProbValueChanged, true);
            app.WordFixProb.Value = 0.9;
            app.WordAutocorrectedProbLabel = uilabel(app.LeftPanel);
            app.WordAutocorrectedProbLabel.Text = 'Word Autocorrected Prob. (p)';
            app.WordSuggestedProb = uislider(app.LeftPanel);
            app.WordSuggestedProb.Limits = [0 1];
            app.WordSuggestedProb.Value = 0.6;
            app.WordSuggestedProbLabel = uilabel(app.LeftPanel);
            app.WordSuggestedProbLabel.Text = 'Word Suggested Prob. (p)';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 'Execution Time (s)')
            ylabel(app.UIAxes, 'PDF')
            app.UIAxesCER = uiaxes(app.RightPanel);
            xlabel(app.UIAxesCER, 'CER (%)')
            ylabel(app.UIAxesCER, 'PDF')
            app.UIAxesKSPC = uiaxes(app.RightPanel);
            xlabel(app.UIAxesKSPC, 'Min. KSPC (n)')
            ylabel(app.UIAxesKSPC, 'PDF')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EditStrategyAnalysisTool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end